classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UploadimageforColorfilterButton  matlab.ui.control.Button
        UploadimageforGaussianButton  matlab.ui.control.Button
        UploadimageforEnhancementButton  matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        UIAxes1_1                     matlab.ui.control.UIAxes
        UIAxes1_2                     matlab.ui.control.UIAxes
        UIAxes1_3                     matlab.ui.control.UIAxes
        UIAxes1_4                     matlab.ui.control.UIAxes
        UIAxes1_5                     matlab.ui.control.UIAxes
        UIAxes1_6                     matlab.ui.control.UIAxes
        UIAxes1_7                     matlab.ui.control.UIAxes
        UIAxes1_8                     matlab.ui.control.UIAxes
        UIAxes1_9                     matlab.ui.control.UIAxes
        UIAxes2_1                     matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2_3                     matlab.ui.control.UIAxes
        UIAxes3_1                     matlab.ui.control.UIAxes
        UIAxes3_2                     matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        % ----------------------------------------------------------------------- %
        %                        C O L O R    F I L T E R                         %
        %  colors of HSV example)                                                 %
        %                         Red : 320 ~ 050                                 %
        %                         Green : 050 ~ 170                               %
        %                         Blue : 170 ~ 305                                %
        % ----------------------------------------------------------------------- %

        function I = colorfilter(app, image, range)

            % RGB to HSV conversion
            I = rgb2hsv(image);         
    
            % Normalization range between 0 and 1
            range = range./360;
    
            % Mask creation
            if(size(range,1) > 1), error('Error. Range matriz has too many rows.'); end
            if(size(range,2) > 2), error('Error. Range matriz has too many columns.'); end

            if(range(1) > range(2))
                % Red hue case
                mask = (I(:,:,1)>range(1) & (I(:,:,1)<=1)) + (I(:,:,1)<range(2) & (I(:,:,1)>=0));
            else
                % Regular case
                mask = (I(:,:,1)>range(1)) & (I(:,:,1)<range(2));
            end
    
            % Saturation is modified according to the mask
            I(:,:,2) = mask .* I(:,:,2);
    
            % HSV to RGB conversion
            I = hsv2rgb(I);
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadimageforColorfilterButton
        function UploadimageforColorfilterButtonPushed(app, event)
            global filename;
            global filepath;
            
            [filename, filepath] = uigetfile({'*.png; *.bmp; *.jpg','supported imgaes';...
                                   '*.png', 'Portable Network Graphics(*.png)';...
                                   '*.bmp', 'Bitmap (*.bmp)';...
                                   '*.jpg', 'JPEG(*.jpg)';...
                                   '*.*','All files(*.*)'}, filepath);

            
            % Diverse Gpu image filtering by 이창민, 정원영 for matlab project%

            % Use gpu %
            image = gpuArray(imread([filepath, filename]));
            dimage = im2double(image);
            
            % Sharpened + Equalize %
            gradient = convn(dimage,ones(3)./9,'same') - convn(dimage,ones(5)./25,'same');
            amount = 5;
            sharpened = dimage + amount.*gradient;
            equalized = histeq(dimage);

            % no (R/G/B) series %
            redChannel = dimage(:, :, 1);
            greenChannel = dimage(:, :, 2);
            blueChannel = dimage(:, :, 3);
            nored = cat(3, greenChannel, greenChannel, blueChannel);    % cover from here
            nogreen = cat(3, redChannel, redChannel, blueChannel);
            noblue = cat(3, redChannel, greenChannel, redChannel);      % to here
             
            % Color filter %
            orf = colorfilter(app,dimage,[320 50]);     % red
            ogf = colorfilter(app, dimage,[50 170]);     % green
            obf = colorfilter(app, dimage,[170 305]);    % blue

            % Output %
            imshow(dimage, 'parent', app.UIAxes1_1); title('Original image');
            imshow(sharpened, 'parent', app.UIAxes1_2); title('sharpened image');
            imshow(equalized, 'parent', app.UIAxes1_3); title('Equalized');
            imshow(nored, 'parent', app.UIAxes1_4); title('red to green');
            imshow(nogreen, 'parent', app.UIAxes1_5); title('green to red');
            imshow(noblue, 'parent', app.UIAxes1_6); title('blue to red');
            imshow(orf, [], 'parent', app.UIAxes1_7); title('(filter)only red');
            imshow(ogf, [], 'parent', app.UIAxes1_8); title('(filter)only green');
            imshow(obf, [], 'parent', app.UIAxes1_9); title('(filter)only blue');
           
        end

        % Button pushed function: UploadimageforGaussianButton
        function UploadimageforGaussianButtonPushed(app, event)
            global filename;
            global filepath;
            
            [filename, filepath] = uigetfile({'*.png; *.bmp; *.jpg','supported imgaes';...
                                   '*.png', 'Portable Network Graphics(*.png)';...
                                   '*.bmp', 'Bitmap (*.bmp)';...
                                   '*.jpg', 'JPEG(*.jpg)';...
                                   '*.*','All files(*.*)'}, filepath);
            
            
            % Diverse Gpu image filtering by 이창민, 정원영 for matlab project%

            % Use gpu %
            image = gpuArray(imread([filepath, filename]));
            dimage = im2double(image);
            
            % Gaussian + filtering %
            grayed = mat2gray(image);
            gau = imnoise(grayed, 'gaussian', 0,0.025);
            filtered = medfilt2(gau);

            adjim = imadjust(dimage);     %Adjust non colored photo%


            % Output %
            imshow(imresize(dimage,1),'parent', app.UIAxes2_1);
            imshow(imresize(adjim,1),'parent', app.UIAxes2_2);
            imshow(imresize(filtered,1),'parent', app.UIAxes2_3); 
          
        end

        % Button pushed function: UploadimageforEnhancementButton
        function UploadimageforEnhancementButtonPushed(app, event)
            global filename;
            global filepath;
            
            [filename, filepath] = uigetfile({'*.png; *.bmp; *.jpg','supported imgaes';...
                                   '*.png', 'Portable Network Graphics(*.png)';...
                                   '*.bmp', 'Bitmap (*.bmp)';...
                                   '*.jpg', 'JPEG(*.jpg)';...
                                   '*.*','All files(*.*)'}, filepath);

            
            %image enhancement Model Load%
            load('trainedVDSR-Epoch-100-ScaleFactors-234.mat');
            
            dimage = imread([filepath, filename]);
            original = dimage;
            dimage = im2double(dimage);

            [nrows,ncols,np] = size(dimage);

            Iycbcr = rgb2ycbcr(dimage);
            Iy = Iycbcr(:,:,1);
            Icb = Iycbcr(:,:,2);
            Icr = Iycbcr(:,:,3);

            Iy_bicubic = imresize(Iy,[nrows ncols],'bicubic');
            Icb_bicubic = imresize(Icb,[nrows ncols],'bicubic');
            Icr_bicubic = imresize(Icr,[nrows ncols],'bicubic');

            dimage = gpuArray(activations(net,Iy_bicubic,41));
            dimage = double(dimage);

            Isr = Iy_bicubic + dimage;

            Enhanced = ycbcr2rgb(cat(3,Isr,Icb_bicubic,Icr_bicubic));

            
            imshow(original,'parent', app.UIAxes3_1)
            imshow(Enhanced,'parent', app.UIAxes3_2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadimageforColorfilterButton = uibutton(app.UIFigure, 'push');
            app.UploadimageforColorfilterButton.ButtonPushedFcn = createCallbackFcn(app, @UploadimageforColorfilterButtonPushed, true);
            app.UploadimageforColorfilterButton.Text = 'Upload image for Color filter';
            app.UploadimageforGaussianButton = uibutton(app.UIFigure, 'push');
            app.UploadimageforGaussianButton.ButtonPushedFcn = createCallbackFcn(app, @UploadimageforGaussianButtonPushed, true);
            app.UploadimageforGaussianButton.Text = 'Upload image for Gaussian ';
            app.UploadimageforEnhancementButton = uibutton(app.UIFigure, 'push');
            app.UploadimageforEnhancementButton.ButtonPushedFcn = createCallbackFcn(app, @UploadimageforEnhancementButtonPushed, true);
            app.UploadimageforEnhancementButton.Text = 'Upload image for Enhancement';
            app.Label = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'32153682'; '이창민'; '32154240'; '정원영'};
            app.UIAxes1_1 = uiaxes(app.UIFigure);
            title(app.UIAxes1_1, '원본')
            app.UIAxes1_1.XTick = [];
            app.UIAxes1_1.YTick = [];
            app.UIAxes1_2 = uiaxes(app.UIFigure);
            title(app.UIAxes1_2, 'sharpened ')
            app.UIAxes1_2.XTick = [];
            app.UIAxes1_2.YTick = [];
            app.UIAxes1_3 = uiaxes(app.UIFigure);
            title(app.UIAxes1_3, 'Equalized')
            app.UIAxes1_3.XTick = [];
            app.UIAxes1_3.YTick = [];
            app.UIAxes1_4 = uiaxes(app.UIFigure);
            title(app.UIAxes1_4, 'red to green')
            app.UIAxes1_4.XTick = [];
            app.UIAxes1_4.YTick = [];
            app.UIAxes1_5 = uiaxes(app.UIFigure);
            title(app.UIAxes1_5, 'green to red')
            app.UIAxes1_5.XTick = [];
            app.UIAxes1_5.YTick = [];
            app.UIAxes1_6 = uiaxes(app.UIFigure);
            title(app.UIAxes1_6, 'blue to red')
            app.UIAxes1_6.XTick = [];
            app.UIAxes1_6.YTick = [];
            app.UIAxes1_7 = uiaxes(app.UIFigure);
            title(app.UIAxes1_7, '(filter)only red')
            app.UIAxes1_7.XTick = [];
            app.UIAxes1_7.YTick = [];
            app.UIAxes1_8 = uiaxes(app.UIFigure);
            title(app.UIAxes1_8, '(filter)only green')
            app.UIAxes1_8.XTick = [];
            app.UIAxes1_8.YTick = [];
            app.UIAxes1_9 = uiaxes(app.UIFigure);
            title(app.UIAxes1_9, '(filter)only blue')
            app.UIAxes1_9.XTick = [];
            app.UIAxes1_9.YTick = [];
            app.UIAxes2_1 = uiaxes(app.UIFigure);
            title(app.UIAxes2_1, '원본')
            app.UIAxes2_1.XTick = [];
            app.UIAxes2_1.YTick = [];
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Adjusted')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Gaussian + filtering')
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.UIAxes3_1 = uiaxes(app.UIFigure);
            title(app.UIAxes3_1, '원본')
            app.UIAxes3_1.XTick = [];
            app.UIAxes3_1.YTick = [];
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Deep learning Enhancement')
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end